classdef Examen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        FiltrarenfButton  matlab.ui.control.Button
        FiltrarentButton  matlab.ui.control.Button
        FiltrosPanel      matlab.ui.container.Panel
        ListBox_2         matlab.ui.control.ListBox
        VentanasPanel     matlab.ui.container.Panel
        ListBox           matlab.ui.control.ListBox
        RightPanel        matlab.ui.container.Panel
        REPRODUCIRButton  matlab.ui.control.Button
        GRABARButton      matlab.ui.control.Button
        AplicarFFTButton  matlab.ui.control.Button
        UIAxes_2          matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        x       %Señal original dominio del tiempo
        xf      %Señal original dominio de freq
        fs=44100  %Freq de muestreo
        sft      %Señal filtrada dominio del tiempo
        sff      %Señal filtrada 
        segs=0.5    %Tiempo en segundos para la señal
        w1      %Vector frecuencia angular
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
   
        end

        % Button pushed function: AplicarFFTButton
        function AplicarFFTButtonPushed(app, event)
           y=fft(app.x);
           n=(app.fs)*(app.segs);
           p1=2.*(abs(y(1:n/2)/n));
           plot(app.UIAxes_2,p1);
           app.UIAxes_2.Title.String="Señal Original";
           app.UIAxes_2.XLabel.String="Frecuencia";
           app.xf=p1;
           
           app.FiltrosPanel.Enable="on";
           app.AplicarFFTButton.Enable="off";
        end

        % Button pushed function: GRABARButton
        function GRABARButtonPushed(app, event)
            senal_salida=audiorecorder(app.fs,16,1); %Creacion del objeto de grabacion
            msgbox('Empezando Grabacion','Grabadora');%Mensaje de información
            recordblocking(senal_salida,app.segs); %Grabacion del sonido
            msgbox('Terminando grabación');%Mensaje de informacion
            %Paso los valores del objeto a una señal
            app.x=getaudiodata(senal_salida,'single');
            %Se graba y se guarda la señal
            audiowrite("audio.wav", app.x,app.fs);

             [y,Fs0] = audioread("audio.wav");   %Abre el archivo de la grabacion

            plot(app.UIAxes,app.x);  
            app.UIAxes.Title.String="Señal Original";
            app.UIAxes.XLabel.String="Tiempo";
        
            app.VentanasPanel.Enable="on";
            app.REPRODUCIRButton.Enable="on";

            app.FiltrarentButton.Enable="off";
            app.FiltrosPanel.Enable="off";
            app.AplicarFFTButton.Enable="off";
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.VentanasPanel.Enable="off";
            value = app.ListBox.Value;
            w=0;
            n=(app.fs)*(app.segs);
            switch value
                case 'Rectangular'
                     w=rectwin(n);

               case  'Taylor'
                    w=taylorwin(n);
                    
                case  'Hanning' 
                    w=hann(n);
                    
                case  'Gauss'
                    w=gausswin(n);
                    
                   
                case  'Triangular'
                    w=triang(n);
                    
                otherwise
                   
            end
                 %Venaneo
                    y = app.x(1:n).*w;
                    y = y.*repmat(w,1,150);
                    plot(app.UIAxes,y);
                    app.x=y;

                app.AplicarFFTButton.Enable="on";
                app.REPRODUCIRButton.Enable="off";
        end

        % Button pushed function: FiltrarentButton
        function FiltrarentButtonPushed(app, event)
           plot(app.UIAxes,app.x)
            app.UIAxes.Title.String="Señal Original";
            app.UIAxes.XLabel.String="Tiempo";

            plot(app.UIAxes_2,app.sft);
            app.UIAxes_2.Title.String="Señal Filtrada";
            app.UIAxes_2.XLabel.String="Tiempo";
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            value = app.ListBox_2.Value;
            wn=0;
            b=0;
            switch value                
                case 'Pasa altas'
                    wn=500/(app.fs);
                    b=fir1(30,wn,"high");
                case 'Pasa bajas'
                    wn=1000/(app.fs);
                    b=fir1(30,wn,"low");
                case 'Pasa banda'
                    wn=[500/(app.fs) 2000/(app.fs)];
                    b=fir1(30,wn,"bandpass");
                case 'Rechaza banda'
                    wn=[500/(app.fs) 2000/(app.fs)];
                    b=fir1(30,wn,"stop");
                otherwise
            end
                 
            %Filtrar en el dominio del tiempo
            app.sft=filter(b,1,app.x);
            plot(app.UIAxes, app.sft);

            %Filtrar en el dominio de la frecuencia
            [h,w]=freqz(b,1);       %Aplicar respuesta de freq
            app.w1=w;
            app.sff=abs(h);
            app.FiltrarentButton.Enable="on";
            app.FiltrarenfButton.Enable="on";
        end

        % Button pushed function: REPRODUCIRButton
        function REPRODUCIRButtonPushed(app, event)
            [y,Fs0] = audioread("audio.wav");   %Abre el archivo de la grabacion
            %Reproducir audio
            sound(app.x,Fs0)    %Reproduce el audio    
        end

        % Button pushed function: FiltrarenfButton
        function FiltrarenfButtonPushed(app, event)
            plot(app.UIAxes,app.xf)
            app.UIAxes.Title.String="Señal Original";
            app.UIAxes.XLabel.String="Frecuencia";

            plot(app.UIAxes_2,(app.w1*app.fs)/pi,app.sff);
            app.UIAxes_2.Title.String="Señal Filtrada";
            app.UIAxes_2.XLabel.String="Frecuencia";
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {513, 513};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.VentanasPanel = uipanel(app.LeftPanel);
            app.VentanasPanel.Enable = 'off';
            app.VentanasPanel.Title = 'Ventanas';
            app.ListBox = uilistbox(app.VentanasPanel);
            app.ListBox.Items = {'Rectangular', 'Taylor', 'Hanning', 'Gauss', 'Triangular'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.FiltrosPanel = uipanel(app.LeftPanel);
            app.FiltrosPanel.Enable = 'off';
            app.FiltrosPanel.Title = 'Filtros';
            app.ListBox_2 = uilistbox(app.FiltrosPanel);
            app.ListBox_2.Items = {'Pasa altas', 'Pasa bajas', 'Pasa banda', 'Rechaza banda'};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = {};
            app.FiltrarentButton = uibutton(app.LeftPanel, 'push');
            app.FiltrarentButton.ButtonPushedFcn = createCallbackFcn(app, @FiltrarentButtonPushed, true);
            app.FiltrarentButton.Enable = 'off';
            app.FiltrarentButton.Text = 'Filtrar en t';
            app.FiltrarenfButton = uibutton(app.LeftPanel, 'push');
            app.FiltrarenfButton.ButtonPushedFcn = createCallbackFcn(app, @FiltrarenfButtonPushed, true);
            app.FiltrarenfButton.Enable = 'off';
            app.FiltrarenfButton.Text = 'Filtrar en f';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Grafico')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Grafico')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.AplicarFFTButton = uibutton(app.RightPanel, 'push');
            app.AplicarFFTButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarFFTButtonPushed, true);
            app.AplicarFFTButton.Enable = 'off';
            app.AplicarFFTButton.Text = {'Aplicar '; 'FFT'};
            app.GRABARButton = uibutton(app.RightPanel, 'push');
            app.GRABARButton.ButtonPushedFcn = createCallbackFcn(app, @GRABARButtonPushed, true);
            app.GRABARButton.Text = 'GRABAR';
            app.REPRODUCIRButton = uibutton(app.RightPanel, 'push');
            app.REPRODUCIRButton.ButtonPushedFcn = createCallbackFcn(app, @REPRODUCIRButtonPushed, true);
            app.REPRODUCIRButton.Enable = 'off';
            app.REPRODUCIRButton.Text = 'REPRODUCIR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Examen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end