classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        ColorListBox       matlab.ui.control.ListBox
        ColorListBoxLabel  matlab.ui.control.Label
        ListBox            matlab.ui.control.ListBox
        RightPanel         matlab.ui.container.Panel
        ASlider            matlab.ui.control.Slider
        ASliderLabel       matlab.ui.control.Label
        PSlider            matlab.ui.control.Slider
        PSliderLabel       matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        p       %Parametro para frecuencia
        c='r'       %Color de la linea en el plot
        a       %Parametro para la amplitud
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.p=app.PSlider.Value;
            app.a=app.ASlider.Value;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
             switch value
                case 'Espectrograma'
                    %N = 1024;
                    %n = 0:N-1;                    
                    %w0 = 2*pi/5;
                    t=0:0.001:2;
                    x = chirp(t,0,1,150*(app.p));
                    myAxe = app.UIAxes;
                    [S,F,T] = spectrogram(x,128,120,128,1e3);
                    imagesc(myAxe, T, F, log(1+abs(S)) ); %plot the log spectrum
                    set(myAxe,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom
                    app.UIAxes.Title.String="Espectrograma";
                    app.UIAxes.XLabel.String="Tiempo (segundos)";
                    app.UIAxes.YLabel.String="Frecuencia (Hz)";
    
                 case 'Electroencefalograma'
                    t = linspace(0, 1, 500);                                            % Time Vector
                    EEG = rand(10, 500)*0.005;                                          % Simulate EEG (mV)
                    ofst = [1:size(EEG,1)]*0.005 + 0.001;                               % +Offseta Vector
                    EEGp = bsxfun(@plus, EEG', ofst)';    
                    plot(app.UIAxes, t, EEGp)                                                       % Plot EEG
                    app.UIAxes.Title.String="Electroencefalograma";
                    app.UIAxes.XLabel.String="Tiempo (segundos)";
                    app.UIAxes.YLabel.String="Medidas";
                    %axis([xlim    0  0.055])                                            % Set Axis Limits
                    %ChC = regexp(sprintf('Ch-%02d ', [1:size(EEG,1)]), ' ', 'split');   % Y-Tick Labels
                    %yt = ofst+0.0025;                                                   % Y-Yick Positions
                    %set(gca, 'YTick',yt, 'YTickLabel',ChC(1:end-1))   
                 case 'Voltaje CA'
                    Fs = 8000;                   % samples per second
                    dt = 1/Fs;                   % seconds per sample
                    StopTime = 0.25;             % seconds
                    t = (0:dt:StopTime-dt)';     % seconds
                    %%Sine wave:
                    Fc = 60;                     % hertz
                    x = (app.a)*(sin(2*pi*Fc*t*(app.p)));
                    % Plot the signal versus time:
                    plot(app.UIAxes,t,x);
                    app.UIAxes.Title.String="Voltaje CA";
                    app.UIAxes.XLabel.String="Tiempo (segundos)";
                    app.UIAxes.YLabel.String="Corriente";

                    % get the handles to the plotted lines
                    h = app.UIAxes.Children;    %Arreglo de objetos graficos 
                    % get handle of a single line plot
                    line1 = h(1);
                    % change the properties of the line plot based on user input.
                    line1.Color = app.c;
                 case 'Luz'
                     %clc; close all; clear all;

                    %Generar una senal de amplitud de 12, frecuencia de 7 hz, fase de 0 y Fm
                    %variable
                    A=5;    %Amplitud
                    F=2;    %Frecuencia de 7Hz
                    Fm=1000; %Frecuencia de muestreo, 1000 muestras por segundo
                    n=0:1/Fm:1;     %Declaracion de mi tiempo discreto
                    Fase=0; %Fase
                    X=(app.a)*(sin(2*pi*F*(app.p)*n+Fase)); %Se genera la senal senoidal
                    plot(app.UIAxes,n,X);
                    app.UIAxes.Title.String="Onda de Luz";
                    app.UIAxes.XLabel.String="Tiempo (segundos)";
                    app.UIAxes.YLabel.String="Amplitud";

                    % get the handles to the plotted lines
                    h = app.UIAxes.Children;    %Arreglo de objetos graficos 
                    % get handle of a single line plot
                    line1 = h(1);
                    % change the properties of the line plot based on user input.
                    line1.Color = app.c;
                 case 'Reloj'
                     A=.2;    %Amplitud
                    F=.5;    %Frecuencia de 7Hz
                    Fm=1000 %Frecuencia de muestreo, 1000 muestras por segundo
                    n=0:2/Fm:2;     %Declaracion de mi tiempo discreto
                    Fase=0; %Fase
                    X=(app.a)*(sin(2*pi*F*(app.p)*n+Fase)); %Se genera la senal senoidal
                    plot(app.UIAxes,n,X);
                    app.UIAxes.Title.String="Reloj";
                    app.UIAxes.XLabel.String="Tiempo (segundos)";
                    app.UIAxes.YLabel.String="Amplitud";

                    % get the handles to the plotted lines
                    h = app.UIAxes.Children;    %Arreglo de objetos graficos 
                    % get handle of a single line plot
                    line1 = h(1);
                    % change the properties of the line plot based on user input.
                    line1.Color = app.c;
                 case 'Sen-Cos'

                    A1=(app.a);    %Amplitud
                    F=1;    %Frecuencia de 7Hz
                    Fm=1000 %Frecuencia de muestreo, 1000 muestras por segundo
                    n=0:2/Fm:2;     %Declaracion de mi tiempo discreto
                    Fase=0; %Fase
                    X=A1*(sin(2*pi*F*(app.p)*n+Fase)); %Se genera la senal senoidal
                    plot(app.UIAxes,n,X)
                    app.UIAxes.Title.String="Seno Coseno";
                    app.UIAxes.XLabel.String="Tiempo (segundos)";
                    app.UIAxes.YLabel.String="Amplitud";
                    
                    A2=(app.a);    
                    F1=1.5;    
                    Fase1=0;
                    hold(app.UIAxes,'on')
                    X1=A2*(cos(2*pi*F1*(app.p)*n+Fase1));
                    plot(app.UIAxes,n,X1)
                    hold(app.UIAxes,'off')        

                    % get the handles to the plotted lines
                    h = app.UIAxes.Children;    %Arreglo de objetos graficos 
                    % get handle of a single line plot
                    line1 = h(1);
                    % change the properties of the line plot based on user input.
                    line1.Color = app.c;


                    case 'Sismo'

                    %A = 16;
                    F = 55;
                    %Fm = 1000;
                    n = 0:1/F:1;
                    Fase = 0;
                    X = (app.a)*sin(2*pi*F*(app.p)*n+Fase);
                    plot(app.UIAxes,X)

                    app.UIAxes.Title.String="Sismo";
                    app.UIAxes.XLabel.String="Tiempo";
                    app.UIAxes.YLabel.String="Escala Ritchter";

                    % get the handles to the plotted lines
                    h = app.UIAxes.Children;    %Arreglo de objetos graficos 
                    % get handle of a single line plot
                    line1 = h(1);
                    % change the properties of the line plot based on user input.
                    line1.Color = app.c;


                 case 'Sen Constante'

                    t=-10:0.0001:10;
                    y=(app.a)*sin(t*(app.p))./t;
                    plot(app.UIAxes,t,y,'--r')

                    app.UIAxes.Title.String="Sen C";
                    app.UIAxes.XLabel.String="Tiempo";
                    app.UIAxes.YLabel.String="Amplitud";

                    % get the handles to the plotted lines
                    h = app.UIAxes.Children;    %Arreglo de objetos graficos 
                    % get handle of a single line plot
                    line1 = h(1);
                    % change the properties of the line plot based on user input.
                    line1.Color = app.c;

                 case 'Sen-Cos Discretizado'

                    X = linspace(0,2*pi,50)';
                    Y = [(app.a)*cos(X*(app.p)), (app.a)*sin(X*(app.p))];
                    stem(app.UIAxes,Y)
                    app.UIAxes.Title.String="Sen-Cos Discretizado";
                    app.UIAxes.XLabel.String="Tiempo";
                    app.UIAxes.YLabel.String="Amplitud";

                    % get the handles to the plotted lines
                    h = app.UIAxes.Children;    %Arreglo de objetos graficos 
                    % get handle of a single line plot
                    line1 = h(1);
                    % change the properties of the line plot based on user input.
                    line1.Color = app.c;

                 case 'Escalon Unitario'

                    t = (-5:0.5:5)';   
                    unitstep = t>=0;
                    stem(app.UIAxes,t,(app.a)*unitstep);
                    app.UIAxes.Title.String="Escalon Unitario";
                    app.UIAxes.XLabel.String="Tiempo";
                    app.UIAxes.YLabel.String="Amplitud";

                     % get the handles to the plotted lines
                    h = app.UIAxes.Children;    %Arreglo de objetos graficos 
                    % get handle of a single line plot
                    line1 = h(1);
                    % change the properties of the line plot based on user input.
                    line1.Color = app.c;

             end
             
        end

        % Value changed function: PSlider
        function PSliderValueChanged(app, event)
            app.p = app.PSlider.Value;
            ListBoxValueChanged(app,event);
        end

        % Value changed function: ColorListBox
        function ColorListBoxValueChanged(app, event)
            value = app.ColorListBox.Value;
            switch value
                case "Azul"
                    app.c='b';
                case "Rojo"
                    app.c='r';
                case "Verde"
                    app.c='g';
                case "Amarillo"
                    app.c='y';
                case "Cian"
                    app.c='c';
                case "Magenta"
                    app.c='m';
            end
            ListBoxValueChanged(app,event);
        end

        % Value changed function: ASlider
        function ASliderValueChanged(app, event)
            app.a = app.ASlider.Value;
            ListBoxValueChanged(app,event);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {431, 431};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {186, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ListBox = uilistbox(app.LeftPanel);
            app.ListBox.Items = {'Espectrograma', 'Electroencefalograma', 'Voltaje CA', 'Luz', 'Reloj', 'Sen-Cos', 'Sismo', 'Sen Constante', 'Sen-Cos Discretizado', 'Escalon Unitario'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Espectrograma';
            app.ColorListBoxLabel = uilabel(app.LeftPanel);
            app.ColorListBoxLabel.Text = 'Color';
            app.ColorListBox = uilistbox(app.LeftPanel);
            app.ColorListBox.Items = {'Azul', 'Verde', 'Rojo', 'Cian', 'Amarillo', 'Magenta'};
            app.ColorListBox.ValueChangedFcn = createCallbackFcn(app, @ColorListBoxValueChanged, true);
            app.ColorListBox.Value = 'Rojo';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Grafico')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PSliderLabel = uilabel(app.RightPanel);
            app.PSliderLabel.Text = 'P';
            app.PSlider = uislider(app.RightPanel);
            app.PSlider.Limits = [0 5];
            app.PSlider.MajorTicks = [0 1 2 3 4 5];
            app.PSlider.ValueChangedFcn = createCallbackFcn(app, @PSliderValueChanged, true);
            app.PSlider.MinorTicks = [0 1 2 3 4 5];
            app.PSlider.Value = 1;
            app.ASliderLabel = uilabel(app.RightPanel);
            app.ASliderLabel.Text = 'A';
            app.ASlider = uislider(app.RightPanel);
            app.ASlider.Limits = [0 5];
            app.ASlider.MajorTicks = [0 1 2 3 4 5];
            app.ASlider.ValueChangedFcn = createCallbackFcn(app, @ASliderValueChanged, true);
            app.ASlider.MinorTicks = [0 1 2 3 4 5];
            app.ASlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end