classdef Examen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        OtraOperacionButton           matlab.ui.control.Button
        ResultadoButton               matlab.ui.control.Button
        SeleccionelaSeal2Panel        matlab.ui.container.Panel
        ListBox_3                     matlab.ui.control.ListBox
        SeleccionelaSealPanel         matlab.ui.container.Panel
        ListBox                       matlab.ui.control.ListBox
        OperacinButtonGroup           matlab.ui.container.ButtonGroup
        EscalamientodeamplitudButton  matlab.ui.control.ToggleButton
        IntegracionButton             matlab.ui.control.ToggleButton
        VIndependienteCheckBox        matlab.ui.control.CheckBox
        VDependienteCheckBox          matlab.ui.control.CheckBox
        CorrimientoeneltiempoButton   matlab.ui.control.ToggleButton
        EscalamientoeneltiempoButton  matlab.ui.control.ToggleButton
        ReflexinButton                matlab.ui.control.ToggleButton
        MultiplicacinButton           matlab.ui.control.ToggleButton
        DiferenciacinButton           matlab.ui.control.ToggleButton
        SumaButton                    matlab.ui.control.ToggleButton
        RightPanel                    matlab.ui.container.Panel
        Corrimiento2Panel             matlab.ui.container.Panel
        ValorSpinner_2                matlab.ui.control.Spinner
        ValorSpinner_2Label           matlab.ui.control.Label
        Escalamientodetiempo2Slider   matlab.ui.control.Slider
        Escalamientodetiempo2SliderLabel  matlab.ui.control.Label
        AmplitudSlider_2              matlab.ui.control.Slider
        AmplitudSlider_2Label         matlab.ui.control.Label
        CorrimientoPanel              matlab.ui.container.Panel
        ValorSpinner                  matlab.ui.control.Spinner
        ValorSpinnerLabel             matlab.ui.control.Label
        ColordelaGraficaPanel         matlab.ui.container.Panel
        ColordelaGraficaListBox       matlab.ui.control.ListBox
        Amplitud2Slider               matlab.ui.control.Slider
        Amplitud2SliderLabel          matlab.ui.control.Label
        EscalamientodetiempoSlider    matlab.ui.control.Slider
        EscalamientodetiempoSliderLabel  matlab.ui.control.Label
        TipodeSealButtonGroup         matlab.ui.container.ButtonGroup
        ContinuaButton                matlab.ui.control.RadioButton
        DiscretaButton                matlab.ui.control.RadioButton
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        f       %Parametro para la frecuencia
        a       %Parametro para la amplitud
        f2       %Parametro para la frecuencia
        a2       %Parametro para la amplitud
        c='r';       %Color de la linea en el plot
        r=1 ;      %Parametro para la reflexion
        d       % Parametro para corrimiento
        d2       % Parametro para corrimiento
        Fs      %Frecuencia  muestral
        x       %Variable independiente
        x2       %Variable independiente 2
        xint;     %Parametro para integrar
        num_signal=0; %Numero de señales
        aux_res     %Variable auxiliar para la suma/multiplicacion

    end


    methods (Access = public)
        
        function diferenciarFuncion(app)
        
                hold(app.UIAxes,'on');
                y=diff(app.x).*app.Fs;
                n = (-5:1/app.Fs:5);
                if app.DiscretaButton.Value
                    stem(app.UIAxes,n(:,1:length(y)),y,'b');
                else
                    plot(app.UIAxes,n(:,1:length(y)),y,'b');
                end
                

                hold(app.UIAxes,'off')  ;

        end
        
        function integrarFuncion(app)
                hold(app.UIAxes,'on');
                
                n = (-5:1/app.Fs:5);
                y=app.xint;
               
                if app.DiscretaButton.Value
                    stem(app.UIAxes,n(:,1:length(y)),y,'b');
                else
                    plot(app.UIAxes,n(:,1:length(y)),y,'b');
                end                

                hold(app.UIAxes,'off')  ;
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.f=app.EscalamientodetiempoSlider.Value;
            app.a=app.AmplitudSlider_2.Value;
            app.f2=app.Escalamientodetiempo2Slider.Value;
            app.a2=app.Amplitud2Slider.Value;
            app.d=app.ValorSpinner.Value;
            app.d2=app.ValorSpinner_2.Value;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.Fs=5;
            ylabel="x[n]";
            syms nt;

            if app.ContinuaButton.Value
                app.Fs=80;
                 ylabel="x(n)";
            end

             n = (-5:1/app.Fs:5);
             titulo="Grafico";
             switch value
                 case 'Escalon Unitario'
                    
                    app.x = (n*app.r)+app.d>=0;   
                    titulo="Escalón Unitario";
                    
                 case 'Rampa Unitaria'
                    
                     escalon = (n*app.r)+app.d>=0; 
                     app.x = ((n*app.r)+app.d).*escalon;
                     titulo="Rampa Unitaria";

                 case 'Exponencial'
                    
                     app.x=(app.a).^((n+app.d)*app.r);
                     titulo="Exponencial";

                     app.xint=int((app.r)*(app.a).^nt,nt);%Calcula la integral con la variable simbolica
                     app.xint= double(subs(app.xint,nt,n)); %Sustituye la variable simbolica con los valores de n 

                 case 'Exponencial Compleja'    
                     
                     e=(cos(app.f*pi*(app.r)*(n+app.d))+(1i*sin((app.f)*pi*(app.r)*(n+app.d))));
                     app.x=(e.^n);

                     app.xint=int(cos(app.f*pi*(app.r)*nt)+(1i*sin((app.f)*pi*(app.r)*nt)),nt);%Calcula la integral con la variable simbolica
                     app.xint= double(subs(app.xint,nt,n)); %Sustituye la variable simbolica con los valores de n 

                     titulo="Exponencial Compleja";
                     
                 case 'Senoidal'
                     app.x=(app.a)*sin(app.f*2*pi*n+app.d*app.r);
                     titulo="Senoidal";

                     
                     app.xint=int((app.a)*sin(app.f*2*pi*(app.r)*(nt+app.d)),nt);   %Calcula la integral con la variable simbolica
                     app.xint= double(subs(app.xint,nt,n)); %Sustituye la variable simbolica con los valores de n 
                     

                 case 'Onda Cuadrada'

                    app.x =(app.a)*square(app.f*2*pi*(app.r*n)+app.d);
                    titulo="Onda Cuadrada";
                
                  
                 case 'Dientes de Sierra'

                    app.x = (app.a)*sawtooth(app.f*2*pi*(app.r*n)+app.d,0.5);      %0.5 para generar una onda simétrica
                    titulo="Dientes de Sierra";

                    
                 otherwise

             end


             if app.DiscretaButton.Value
                stem(app.UIAxes,n,app.x);      %Discreto    

            else
               plot(app.UIAxes,n,app.x);     %Continuo
                  
             end


             app.UIAxes.Title.String=titulo;
             app.UIAxes.XLabel.String="n";
             app.UIAxes.YLabel.String=ylabel;
      

              h = app.UIAxes.Children;    %Arreglo de objetos graficos 
              line1 = h(1);          %Selecciona la linea 1 del grafico
              line1.Color = app.c;    %Aplicar color

              app.num_signal=1;

              app.TipodeSealButtonGroup.Enable='on';
              if app.SeleccionelaSeal2Panel.Enable=="off"
                    app.OperacinButtonGroup.Enable="on";
              end             
              app.ColordelaGraficaPanel.Enable="on";

        end

        % Value changed function: EscalamientodetiempoSlider
        function EscalamientodetiempoSliderValueChanged(app, event)
            app.f = app.EscalamientodetiempoSlider.Value;
            ListBoxValueChanged(app,event);
        end

        % Value changed function: ColordelaGraficaListBox
        function ColordelaGraficaListBoxValueChanged(app, event)
            value = app.ColordelaGraficaListBox.Value;
            switch value
                case "Azul"
                    app.c='b';
                case "Rojo"
                    app.c='r';
                case "Verde"
                    app.c='g';
                case "Amarillo"
                    app.c='y';
                case "Cian"
                    app.c='c';
            end
            ListBoxValueChanged(app,event);
        end

        % Value changed function: Amplitud2Slider
        function Amplitud2SliderValueChanged(app, event)
            app.a2 = app.Amplitud2Slider.Value;
            ListBox_3ValueChanged(app,event);
        end

        % Selection changed function: TipodeSealButtonGroup
        function TipodeSealButtonGroupSelectionChanged(app, event)
            ListBoxValueChanged(app,event);            
        end

        % Value changed function: VDependienteCheckBox
        function VDependienteCheckBoxValueChanged(app, event)
            value = app.VDependienteCheckBox.Value;
            if  value==0
                app.SumaButton.Enable="off";
                app.MultiplicacinButton.Enable="off";
                app.DiferenciacinButton.Enable="off";
                app.IntegracionButton.Enable="off";
                app.EscalamientodeamplitudButton.Enable="off";
            else
                app.SumaButton.Enable="on";
                app.MultiplicacinButton.Enable="on";
                app.DiferenciacinButton.Enable="on";
                app.IntegracionButton.Enable="on";
                app.EscalamientodeamplitudButton.Enable="on";
            end
                            
        end

        % Value changed function: VIndependienteCheckBox
        function VIndependienteCheckBoxValueChanged(app, event)
            value = app.VIndependienteCheckBox.Value;
            if  value==0
                app.ReflexinButton.Enable="off";
                app.CorrimientoeneltiempoButton.Enable="off";
                app.EscalamientoeneltiempoButton.Enable="off";

                app.r=1;
                app.CorrimientoPanel.Visible= "off";
                app.EscalamientodetiempoSlider.Visible= "off";
            else
                app.ReflexinButton.Enable="on";
                app.CorrimientoeneltiempoButton.Enable="on";
                app.EscalamientoeneltiempoButton.Enable="on";

            end
            
        end

        % Selection changed function: OperacinButtonGroup
        function OperacinButtonGroupSelectionChanged(app, event)
            
           if app.EscalamientodeamplitudButton.Value
                app.AmplitudSlider_2.Visible= "on";            
            elseif app.SumaButton.Value
                app.OperacinButtonGroup.Enable="off";
                app.SeleccionelaSeal2Panel.Enable= "on";

                app.AmplitudSlider_2.Visible="on";
                app.EscalamientodetiempoSlider.Visible="on";

                app.CorrimientoPanel.Visible="on";

                app.aux_res="s";

            elseif app.MultiplicacinButton.Value
                app.OperacinButtonGroup.Enable="off";
                app.SeleccionelaSeal2Panel.Enable= "on";

                app.AmplitudSlider_2.Visible="on";
                app.EscalamientodetiempoSlider.Visible="on";

                app.CorrimientoPanel.Visible="on";

                app.aux_res="m";
            elseif app.DiferenciacinButton.Value
                ListBoxValueChanged(app,event); 
                diferenciarFuncion(app);
            elseif app.IntegracionButton.Value
                integrarFuncion(app);
            elseif app.ReflexinButton.Value
                app.r=(app.r)*-1;
                ListBoxValueChanged(app,event); 
            elseif app.CorrimientoeneltiempoButton.Value
                app.CorrimientoPanel.Visible= "on";
            elseif app.EscalamientoeneltiempoButton.Value
                app.EscalamientodetiempoSlider.Visible= "on";
            end
   
        end

        % Value changed function: ValorSpinner
        function ValorSpinnerValueChanged(app, event)
            app.d = app.ValorSpinner.Value;
            ListBoxValueChanged(app,event);    

        end

        % Value changed function: ListBox_3
        function ListBox_3ValueChanged(app, event)
            value = app.ListBox_3.Value;
            app.Fs=8;

            if app.ContinuaButton.Value
                app.Fs=80;
            end

            app.AmplitudSlider_2.Visible="on";
            app.Escalamientodetiempo2Slider.Visible="on";
            app.ResultadoButton.Enable="on";
            app.OtraOperacionButton.Enable="on";
            app.Corrimiento2Panel.Visible="on";
            app.Amplitud2Slider.Visible="on";


            if(app.num_signal>1)
              h = app.UIAxes.Children;    %Arreglo de objetos graficos 
              line1 = h(1);      
              delete(line1);
            end
             

             n = (-5:1/app.Fs:5);
             switch value
                 case 'Escalon Unitario'
                    
                    app.x2 = n+app.d2>=0;   
                    
                 case 'Rampa Unitaria'
                    
                     escalon = n+app.d2>=0; 
                     app.x2 = (n+app.d2).*escalon;

                 case 'Exponencial'
                    
                     app.x2=(app.a2).^((n+app.d2));

                 case 'Exponencial Compleja'    
                     
                     e=(cos(app.f2*pi*(n+app.d2))+(1i*sin((app.f2)*pi*(n+app.d2))));
                     app.x2=(e.^n);

                     
                 case 'Senoidal'
                     app.x2=(app.a2)*sin(app.f2*2*pi*n+app.d2);

                 case 'Onda Cuadrada'

                    app.x2 =(app.a2)*square(app.f2*2*pi*n+app.d2);
                

                 case 'Dientes de Sierra'

                    app.x2 = (app.a2)*sawtooth(app.f2*2*pi*n+app.d2,0.5);      %0.5 para generar una onda simétrica
                 otherwise

             end

             hold(app.UIAxes,'on');

             if app.DiscretaButton.Value     

                    stem(app.UIAxes,n,app.x2,"m");
                     
            else
                    plot(app.UIAxes,n,app.x2,"m");   
                  
             end
             hold(app.UIAxes,'off')  ;


            app.num_signal=app.num_signal+1;

              app.TipodeSealButtonGroup.Enable='on';

        end

        % Value changed function: Escalamientodetiempo2Slider
        function Escalamientodetiempo2SliderValueChanged(app, event)
            app.f2 = app.Escalamientodetiempo2Slider.Value;
            ListBox_3ValueChanged(app,event);
        end

        % Value changed function: AmplitudSlider_2
        function AmplitudSlider_2ValueChanged(app, event)
            app.a = app.AmplitudSlider_2.Value;
            ListBoxValueChanged(app,event);
            
        end

        % Button pushed function: ResultadoButton
        function ResultadoButtonPushed(app, event)
            n = (-5:1/app.Fs:5);
            if app.aux_res=="s"
                res=app.x+app.x2;
            else
                res=app.x.*app.x2;
            end


             if app.DiscretaButton.Value     

                    stem(app.UIAxes,n,res,"m");
                     
            else
                    plot(app.UIAxes,n,res,"m");   
                  
             end

             app.UIAxes.Title.String="Resultado de la operacion";

        end

        % Button pushed function: OtraOperacionButton
        function OtraOperacionButtonPushed(app, event)
            app.OperacinButtonGroup.Enable="on";
            app.ListBox_3.Enable="on";
            app.SeleccionelaSeal2Panel.Enable="off";
            app.ResultadoButton.Enable="off";
            app.OtraOperacionButton.Enable="off";

            app.CorrimientoPanel.Visible="off";
            app.Corrimiento2Panel.Visible="off";
            app.AmplitudSlider_2.Visible="off";
            app.Amplitud2Slider.Visible="off";
            app.EscalamientodetiempoSlider.Visible="off";
            app.Escalamientodetiempo2Slider.Visible="off";

            app.ValorSpinner.Value=0;
            app.ValorSpinner_2.Value=0;

             h = app.UIAxes.Children;    %Arreglo de objetos graficos 
             delete(h);

        end

        % Value changed function: ValorSpinner_2
        function ValorSpinner_2ValueChanged(app, event)
            app.d2 = app.ValorSpinner_2.Value;
             ListBox_3ValueChanged(app,event);      
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {574, 574};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {400, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OperacinButtonGroup = uibuttongroup(app.LeftPanel);
            app.OperacinButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OperacinButtonGroupSelectionChanged, true);
            app.OperacinButtonGroup.Enable = 'off';
            app.OperacinButtonGroup.Title = 'Operación';
            app.SumaButton = uitogglebutton(app.OperacinButtonGroup);
            app.SumaButton.Enable = 'off';
            app.SumaButton.Text = 'Suma';
            app.DiferenciacinButton = uitogglebutton(app.OperacinButtonGroup);
            app.DiferenciacinButton.Enable = 'off';
            app.DiferenciacinButton.Text = 'Diferenciación';
            app.MultiplicacinButton = uitogglebutton(app.OperacinButtonGroup);
            app.MultiplicacinButton.Enable = 'off';
            app.MultiplicacinButton.Text = 'Multiplicación';
            app.ReflexinButton = uitogglebutton(app.OperacinButtonGroup);
            app.ReflexinButton.Enable = 'off';
            app.ReflexinButton.Text = 'Reflexión';
            app.EscalamientoeneltiempoButton = uitogglebutton(app.OperacinButtonGroup);
            app.EscalamientoeneltiempoButton.Enable = 'off';
            app.EscalamientoeneltiempoButton.Text = 'Escalamiento en el tiempo';
            app.CorrimientoeneltiempoButton = uitogglebutton(app.OperacinButtonGroup);
            app.CorrimientoeneltiempoButton.Enable = 'off';
            app.CorrimientoeneltiempoButton.Text = 'Corrimiento en el tiempo';
            app.VDependienteCheckBox = uicheckbox(app.OperacinButtonGroup);
            app.VDependienteCheckBox.ValueChangedFcn = createCallbackFcn(app, @VDependienteCheckBoxValueChanged, true);
            app.VDependienteCheckBox.Text = 'V. Dependiente';
            app.VIndependienteCheckBox = uicheckbox(app.OperacinButtonGroup);
            app.VIndependienteCheckBox.ValueChangedFcn = createCallbackFcn(app, @VIndependienteCheckBoxValueChanged, true);
            app.VIndependienteCheckBox.Text = 'V. Independiente';
            app.IntegracionButton = uitogglebutton(app.OperacinButtonGroup);
            app.IntegracionButton.Enable = 'off';
            app.IntegracionButton.Text = 'Integracion';
            app.EscalamientodeamplitudButton = uitogglebutton(app.OperacinButtonGroup);
            app.EscalamientodeamplitudButton.Enable = 'off';
            app.EscalamientodeamplitudButton.Text = 'Escalamiento de amplitud';
            app.EscalamientodeamplitudButton.Value = true;
            app.SeleccionelaSealPanel = uipanel(app.LeftPanel);
            app.SeleccionelaSealPanel.Title = 'Seleccione la Señal';
            app.ListBox = uilistbox(app.SeleccionelaSealPanel);
            app.ListBox.Items = {'Escalon Unitario', 'Rampa Unitaria', 'Exponencial', 'Exponencial Compleja', 'Senoidal', 'Onda Cuadrada', 'Dientes de Sierra'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.SeleccionelaSeal2Panel = uipanel(app.LeftPanel);
            app.SeleccionelaSeal2Panel.Enable = 'off';
            app.SeleccionelaSeal2Panel.Title = 'Seleccione la Señal 2';
            app.ListBox_3 = uilistbox(app.SeleccionelaSeal2Panel);
            app.ListBox_3.Items = {'Escalon Unitario', 'Rampa Unitaria', 'Exponencial', 'Exponencial Compleja', 'Senoidal', 'Onda Cuadrada', 'Dientes de Sierra'};
            app.ListBox_3.ValueChangedFcn = createCallbackFcn(app, @ListBox_3ValueChanged, true);
            app.ListBox_3.Value = {};
            app.ResultadoButton = uibutton(app.LeftPanel, 'push');
            app.ResultadoButton.ButtonPushedFcn = createCallbackFcn(app, @ResultadoButtonPushed, true);
            app.ResultadoButton.Enable = 'off';
            app.ResultadoButton.Text = 'Resultado';
            app.OtraOperacionButton = uibutton(app.LeftPanel, 'push');
            app.OtraOperacionButton.ButtonPushedFcn = createCallbackFcn(app, @OtraOperacionButtonPushed, true);
            app.OtraOperacionButton.Enable = 'off';
            app.OtraOperacionButton.Text = 'Otra Operacion';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Grafico')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TipodeSealButtonGroup = uibuttongroup(app.RightPanel);
            app.TipodeSealButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipodeSealButtonGroupSelectionChanged, true);
            app.TipodeSealButtonGroup.Enable = 'off';
            app.TipodeSealButtonGroup.Title = 'Tipo de Señal';
            app.DiscretaButton = uiradiobutton(app.TipodeSealButtonGroup);
            app.DiscretaButton.Text = 'Discreta';
            app.DiscretaButton.Value = true;
            app.ContinuaButton = uiradiobutton(app.TipodeSealButtonGroup);
            app.ContinuaButton.Text = 'Continua';
            app.EscalamientodetiempoSliderLabel = uilabel(app.RightPanel);
            app.EscalamientodetiempoSliderLabel.Visible = 'off';
            app.EscalamientodetiempoSliderLabel.Text = 'Escalamiento de tiempo';
            app.EscalamientodetiempoSlider = uislider(app.RightPanel);
            app.EscalamientodetiempoSlider.Limits = [0 5];
            app.EscalamientodetiempoSlider.MajorTicks = [0 1 2 3 4 5];
            app.EscalamientodetiempoSlider.ValueChangedFcn = createCallbackFcn(app, @EscalamientodetiempoSliderValueChanged, true);
            app.EscalamientodetiempoSlider.MinorTicks = [0 1 2 3 4 5];
            app.EscalamientodetiempoSlider.Visible = 'off';
            app.EscalamientodetiempoSlider.Value = 1;
            app.Amplitud2SliderLabel = uilabel(app.RightPanel);
            app.Amplitud2SliderLabel.Visible = 'off';
            app.Amplitud2SliderLabel.Text = 'Amplitud 2';
            app.Amplitud2Slider = uislider(app.RightPanel);
            app.Amplitud2Slider.Limits = [0 5];
            app.Amplitud2Slider.ValueChangedFcn = createCallbackFcn(app, @Amplitud2SliderValueChanged, true);
            app.Amplitud2Slider.MinorTicks = [0 1 2 3 4 5];
            app.Amplitud2Slider.Visible = 'off';
            app.Amplitud2Slider.Value = 2;
            app.ColordelaGraficaPanel = uipanel(app.RightPanel);
            app.ColordelaGraficaPanel.Enable = 'off';
            app.ColordelaGraficaPanel.Title = 'Color de la Grafica';
            app.ColordelaGraficaListBox = uilistbox(app.ColordelaGraficaPanel);
            app.ColordelaGraficaListBox.Items = {'Azul', 'Verde', 'Rojo', 'Cian', 'Amarillo'};
            app.ColordelaGraficaListBox.ValueChangedFcn = createCallbackFcn(app, @ColordelaGraficaListBoxValueChanged, true);
            app.ColordelaGraficaListBox.Value = 'Rojo';
            app.CorrimientoPanel = uipanel(app.RightPanel);
            app.CorrimientoPanel.Title = 'Corrimiento';
            app.CorrimientoPanel.Visible = 'off';
            app.ValorSpinnerLabel = uilabel(app.CorrimientoPanel);
            app.ValorSpinnerLabel.Text = 'Valor';
            app.ValorSpinner = uispinner(app.CorrimientoPanel);
            app.ValorSpinner.ValueChangedFcn = createCallbackFcn(app, @ValorSpinnerValueChanged, true);
            app.AmplitudSlider_2Label = uilabel(app.RightPanel);
            app.AmplitudSlider_2Label.Visible = 'off';
            app.AmplitudSlider_2Label.Text = 'Amplitud';
            app.AmplitudSlider_2 = uislider(app.RightPanel);
            app.AmplitudSlider_2.Limits = [0 5];
            app.AmplitudSlider_2.ValueChangedFcn = createCallbackFcn(app, @AmplitudSlider_2ValueChanged, true);
            app.AmplitudSlider_2.MinorTicks = [0 1 2 3 4 5];
            app.AmplitudSlider_2.Visible = 'off';
            app.AmplitudSlider_2.Value = 2;
            app.Escalamientodetiempo2SliderLabel = uilabel(app.RightPanel);
            app.Escalamientodetiempo2SliderLabel.Visible = 'off';
            app.Escalamientodetiempo2SliderLabel.Text = 'Escalamiento de tiempo 2';
            app.Escalamientodetiempo2Slider = uislider(app.RightPanel);
            app.Escalamientodetiempo2Slider.Limits = [0 5];
            app.Escalamientodetiempo2Slider.MajorTicks = [0 1 2 3 4 5];
            app.Escalamientodetiempo2Slider.ValueChangedFcn = createCallbackFcn(app, @Escalamientodetiempo2SliderValueChanged, true);
            app.Escalamientodetiempo2Slider.MinorTicks = [0 1 2 3 4 5];
            app.Escalamientodetiempo2Slider.Visible = 'off';
            app.Escalamientodetiempo2Slider.Value = 1;
            app.Corrimiento2Panel = uipanel(app.RightPanel);
            app.Corrimiento2Panel.Title = 'Corrimiento 2';
            app.Corrimiento2Panel.Visible = 'off';
            app.ValorSpinner_2Label = uilabel(app.Corrimiento2Panel);
            app.ValorSpinner_2Label.Text = 'Valor';
            app.ValorSpinner_2 = uispinner(app.Corrimiento2Panel);
            app.ValorSpinner_2.ValueChangedFcn = createCallbackFcn(app, @ValorSpinner_2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Examen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end